classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        Tab1Grid                        matlab.ui.container.GridLayout
        filter_Panel2                   matlab.ui.container.Panel
        filter_Panel2Grid               matlab.ui.container.GridLayout
        filter_CertificationPart1       matlab.ui.control.EditField
        filter_CNPJ                     matlab.ui.control.EditField
        filter_CNPJCheckBox             matlab.ui.control.CheckBox
        filter_Applicant                matlab.ui.control.EditField
        filter_ApplicantCheckBox        matlab.ui.control.CheckBox
        filter_ManufacturerList         matlab.ui.control.EditField
        filter_ManufacturerListCheckBox  matlab.ui.control.CheckBox
        filter_ProductList              matlab.ui.control.DropDown
        filter_ProductListCheckBox      matlab.ui.control.CheckBox
        filter_CertificationStatus      matlab.ui.control.DropDown
        filter_CertificationStatusCheckBox  matlab.ui.control.CheckBox
        filter_CertificationCheckBox    matlab.ui.control.CheckBox
        filter_Label2                   matlab.ui.control.Label
        filter_Panel1                   matlab.ui.container.Panel
        filter_Panel1Grid               matlab.ui.container.GridLayout
        filter_CheckBox_Notes           matlab.ui.control.CheckBox
        filter_CheckBox_ProductModel    matlab.ui.control.CheckBox
        filter_CheckBox_ProductName     matlab.ui.control.CheckBox
        filter_CheckBox_Manufacturer    matlab.ui.control.CheckBox
        filter_CheckBox_Applicant       matlab.ui.control.CheckBox
        filter_Label1                   matlab.ui.control.Label
        Tab2Grid                        matlab.ui.container.GridLayout
        notes_Tree                      matlab.ui.container.Tree
        notes_addImage                  matlab.ui.control.Image
        notes_Panel2                    matlab.ui.container.Panel
        notes_Panel2Grid                matlab.ui.container.GridLayout
        notes_Value                     matlab.ui.control.TextArea
        notes_ValueLabel                matlab.ui.control.Label
        notes_Type                      matlab.ui.control.DropDown
        notes_TypeLabel                 matlab.ui.control.Label
        notes_Label2                    matlab.ui.control.Label
        notes_Panel1                    matlab.ui.container.Panel
        notes_Panel1Grid                matlab.ui.container.GridLayout
        notes_ProductInfo               matlab.ui.control.HTML
        notes_Label1                    matlab.ui.control.Label
        Tab3Grid                        matlab.ui.container.GridLayout
        report_SelectedProductPanel     matlab.ui.container.Panel
        report_SelectedProductGrid      matlab.ui.container.GridLayout
        TipoDropDown                    matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        report_ComplementaryNotes       matlab.ui.control.TextArea
        report_ComplementaryNotesLabel  matlab.ui.control.Label
        report_MonetaryValue            matlab.ui.control.NumericEditField
        report_MonetaryValueLabel       matlab.ui.control.Label
        report_Quantity                 matlab.ui.control.NumericEditField
        report_QuantityLabel            matlab.ui.control.Label
        report_SelectedProductLabel     matlab.ui.control.Label
        report_Tree                     matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        APPLEIPHONE22Node               matlab.ui.container.TreeNode
        Node_3                          matlab.ui.container.TreeNode
        report_TreeLabel                matlab.ui.control.Label
        Document                        matlab.ui.container.GridLayout
        DocumentGrid                    matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        tool_nRowsGrid                  matlab.ui.container.GridLayout
        tool_nRows                      matlab.ui.control.Label
        tool_nRowsIcon                  matlab.ui.control.Image
        GridLayout                      matlab.ui.container.GridLayout
        Image_6                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        search_Suggestions              matlab.ui.control.ListBox
        search_Table                    matlab.ui.control.Table
        search_Metadata                 matlab.ui.control.Label
        search_entryPointPanel          matlab.ui.container.Panel
        search_entryPointGrid           matlab.ui.container.GridLayout
        search_entryPoint               matlab.ui.control.EditField
        search_entryPointImage          matlab.ui.control.Image
        filter_ContextMenu              matlab.ui.container.ContextMenu
        filter_delButton                matlab.ui.container.Menu
        filter_delAllButton             matlab.ui.container.Menu
        points_ContextMenu              matlab.ui.container.ContextMenu
        points_referenceButton          matlab.ui.container.Menu
        points_editRowButton            matlab.ui.container.Menu
        points_delButton                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General

        RootFolder
        userPath

        filterTable = table('Size',          [0, 9],                                                                      ...
                            'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                            'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})

        rawSCHTable
        cacheSCH       = struct('Column', {}, 'Values', {}, 'tokenizedDoc', {}, 'uniqueTokens', {})
        
        previousSearch   = ''
        lastSearch       = ''
        previousSelectedItemData = 0
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            fcn.winPosition(app)
            % app.UIFigure.WindowState = 'maximized';
        end


        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app)

            app.General = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
            
            if isempty(app.General.Merge.Distance)
                app.General.Merge.Distance = Inf;
            end

            if isempty(app.General.Integration.Trace)
                app.General.Integration.Trace = Inf;
            end

            app.General.version = fcn.startup_Versions("Startup", app.RootFolder);
            
            % tempfolder
            if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                mkdir(fullfile(app.RootFolder, 'Temp'))
            end

            % userPath
            userPaths = class.Constants.userPaths;
            if ~isempty(app.General.userPath)
                userPaths(end+1) = {app.General.userPath};
            end

            for ii = numel(userPaths):-1:1
                if ~isfolder(userPaths{ii})
                    userPaths(ii) = [];
                end
            end
            app.General.userPath = userPaths{end};
            app.userPath         = userPaths{end};
        end


        %-----------------------------------------------------------------%
        function startup_ReadRawSCH(app)

            rawTable = fcn.ReadRawSCHTable(fullfile(app.RootFolder, 'DataBase', 'Produtos_Homologados_Anatel.csv'));
            rawTable = rawTable(:,[2 12 13 14 9 7 16 11 3 4 5]);

            % !! PONTO DE EVOLUÇÃO !!
            % Estabelecer essa lista de colunas em arquivo externo à aplicação...
            cacheColumns = {'Número de Homologação', 'Nome do Fabricante', 'Modelo', 'Nome Comercial', 'Situação do Certificado', 'Tipo do Produto', 'Nome do Solicitante', 'CNPJ do Solicitante'};
            
            tempCache = repmat(struct('Column', '', 'uniqueValues', {{}}, 'uniqueTokens', {{}}), numel(cacheColumns), 1);

            for ii = 1:numel(cacheColumns)
                cacheColumn        = cacheColumns{ii};
                [uniqueValues, ...
                    referenceData] = fcn.PreProcessedData(rawTable.(cacheColumn));
                tokenizedDoc       = tokenizedDocument(uniqueValues);
                uniqueTokens       = unique(cellstr(tokenizedDoc.tokenDetails.Token));

                tempCache(ii)      = struct('Column',       cacheColumn,  ...
                                            'uniqueValues', {uniqueValues}, ...
                                            'uniqueTokens', {unique([uniqueValues; uniqueTokens])});

                rawTable.(sprintf('_%s', cacheColumn)) = referenceData;
            end

            app.rawSCHTable = rawTable;
            app.cacheSCH    = tempCache;
        end


        %-----------------------------------------------------------------%
        function startup_jsBackDoor(app)

            app.jsBackDoor.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', ...
                                                fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));

            % A customização de um componente somente é possível se a figura 
            % já foi completamente renderizada em tela. Espera aqui até a
            % figura estar pronta...
            kk = 0;
            refPause = 1;
            while kk < 100
                kk=kk+1;
                refPause=refPause/2;
                pause(refPause)

                fController = struct(app.UIFigure).Controller;
                if ~isempty(fController) && isprop(fController, 'IsFigureViewReady') && fController.IsFigureViewReady
                    break
                end
            end

            jsBackDoor_compCustomization(app)
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_compCustomization(app)

            % A ideia aqui é criar eventos de clique do mouse cujos componentes 
            % do MATLAB não permitem. No futuro, caso exista uma interação
            % maior MATLAB-JS, teria que criar uma função específica pra receber 
            % os eventos.
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_keyDownListener(app, evt);

            objList = {app.search_entryPoint, app.search_Suggestions};
            for ii = 1:numel(objList)
                objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
            end
            
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_entryPoint',  'DataTag', app.search_entryPoint.UserData));
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_Suggestions', 'DataTag', app.search_Suggestions.UserData));

            % Em relação ao painel de controle, troquei o sistema de visibilidade 
            % de linhas de um Grid por um sistema de abas. Em tese, issodeve tornar 
            % a inicialização do webapp mais eficiente porque somente serão 
            % renderizadas em tela os componentes da aba selecionada. Acontece que 
            % ao fazer isso, a janela de progresso do MATLAB WEB SERVER não é 
            % excluída após a carga do app. Criei o evento para excluí-la.
            sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

            % Customização estéticas:
            ccTools.compCustomizationV2(app.jsBackDoor, app.search_entryPoint, 'borderWidth', '0px');
            ccTools.compCustomizationV2(app.jsBackDoor, app.search_Table,      'fontSize', '11px', 'paddingTop', '0px');
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_keyDownListener(app, event)

        % O evento passa aqui primeiro antes de ir pro callback do componente.
        % Explicando: o objeto ListBox tem um evento "ValueChangedFcn" que
        % também é acionando se o usuário mexer a setas p/ cima e baixa do
        % teclado. Antes de executar o callback do ListBox, contudo, o MATLAB 
        % executa esse aqui...
            switch event.HTMLEventData
                case 'app.search_entryPoint'
                    if ~isempty(app.search_entryPoint.Value)
                        switch event.HTMLEventName
                            case 'ArrowDown'
                                app.previousSelectedItemData = 1;
    
                                set(app.search_Suggestions, 'Visible', 1, 'Value', 1)
                                scroll(app.search_Suggestions, "top")
                                focus(app.search_Suggestions)
            
                            case 'ArrowUp'
                                app.previousSelectedItemData = class.Constants.NumberOfValues;
    
                                set(app.search_Suggestions, 'Visible', 1, 'Value', app.previousSelectedItemData)
                                scroll(app.search_Suggestions, "bottom")
                                focus(app.search_Suggestions)
            
                            case 'Enter'
                                set(app.search_Suggestions, Visible=0, Value={})
                                "Executa consulta..."
    
                            case 'Escape'
                                set(app.search_Suggestions, Visible=0, Value={})
                        end
                    end

                %---------------------------------------------------------%
                case 'app.search_Suggestions'
                    switch event.HTMLEventName
                        case 'ArrowDown'
                            nMaxValues = class.Constants.NumberOfValues;

                            if (app.previousSelectedItemData == nMaxValues) && (app.search_Suggestions.Value == nMaxValues)
                                app.previousSelectedItemData = 0;
                                
                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousSelectedItemData = app.search_Suggestions.Value;
                            end
        
                        case 'ArrowUp'
                            if (app.previousSelectedItemData == 1) && (app.search_Suggestions.Value == 1)
                                app.previousSelectedItemData = 0;

                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousSelectedItemData = app.search_Suggestions.Value;
                            end
        
                        case 'Enter'
                            app.search_entryPoint.Value = app.search_Suggestions.Items{app.search_Suggestions.Value};
                            layout_EntryPointColor(app, false)
                            set(app.search_Suggestions, Visible=0, Value={})
                            "Executa consulta..."

                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function rawColumnData = FindCache_StringListOfSelectedColumn(app, FilterType)
  
            rawColumnData = [];   
                    for ii = 1:numel(FilterType)
                        selectedColumn = string(FilterType(ii));
                        cacheIndex     = find(strcmp(selectedColumn, {app.cacheSCH.Column}), 1);
                        rawColumnData  = [rawColumnData; app.cacheSCH(cacheIndex).uniqueValues];
                    end
        end


        %-----------------------------------------------------------------%
        function rawColumnDataLev = FindCache_TokenListOfSelectedColumn(app, FilterType)
 
            rawColumnDataLev = [];   
                    for ii = 1:numel(FilterType)
                        selectedColumn = FilterType(ii);
                        cacheIndex     = find(strcmp(selectedColumn, {app.cacheSCH.Column}), 1);
                        rawColumnDataLev = [rawColumnDataLev; app.cacheSCH(cacheIndex).uniqueTokens];
                    end
        end


        %-----------------------------------------------------------------%
        function layout_EntryPointColor(app, redFlag)

            if redFlag
                fontColor = [1,0,0];
            else
                fontColor = [0,0,0];
            end
            app.search_entryPoint.FontColor = fontColor;
            drawnow
        end


        %-----------------------------------------------------------------%
        function layout_checkTableVisibility(app)

            if ~app.search_Table.Visible
                % app.search_Table.ColumnName = app.rawSCHTable.Properties.VariableNames(1:11);
                app.search_Table.ColumnName = app.rawSCHTable.Properties.VariableNames(1:19);
                app.search_Table.Visible    = 1;
                app.search_Metadata.Visible = 1;                
            end
        end


        %-----------------------------------------------------------------%
        function filter_main(app, words2Search, FilterType)

            filteredIndex = [];

            for ii = 1:numel(FilterType)
                for jj = 1:numel(words2Search)
                    filteredIndex = [filteredIndex; find(strcmp(app.rawSCHTable.(sprintf('_%s', FilterType(ii))), words2Search{jj}))];
                end
            end
            filteredIndex = unique(filteredIndex, 'stable');

            app.search_Table.UserData = struct('PrimaryIndex', filteredIndex, 'ComplementaryIndex', []);
            app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,1:11);

            layout_checkTableVisibility(app)
            drawnow

            % ROTINA PARA "LIMPAR OS FILRTOS COMPLEMENTARES"
            set(findobj(app.filter_Panel2Grid, 'Type', 'uicheckbox'),  'Value', 0)
            set(findobj(app.filter_Panel2Grid, 'Type', 'uieditfield'), 'Value', '', 'Enable', 0)
            set(findobj(app.filter_Panel2Grid, 'Type', 'uidropdown'),  'Items', {}, 'Enable', 0)

            % app.filter_CertificationCheckBox.Value = 0;
            % app.filter_CertificationStatusCheckBox.Value = 0;
            % app.filter_ProductListCheckBox.Value = 0;
            % app.filter_ManufacturerListCheckBox.Value = 0;
            % app.filter_ApplicantCheckBox.Value = 0;
            % app.filter_CNPJCheckBox.Value = 0;
        end

        %-----------------------------------------------------------------%
        
        function FilterType = filter_Columns_Type(app)
             FilterType = [];

                if app.filter_CheckBox_ProductName.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_ProductName.Text)];
                end
                if app.filter_CheckBox_ProductModel.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_ProductModel.Text)];
                end
                if app.filter_CheckBox_Manufacturer.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_Manufacturer.Text)];
                end
                if app.filter_CheckBox_Applicant.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_Applicant.Text)];
                end
                if app.filter_CheckBox_Notes.Value
                    FilterType = [FilterType; string(app.filter_CheckBox_Notes.Text)];
                end
            FilterType = FilterType;
        end
        

        %-----------------------------------------------------------------%
      

      function filteredComplIndex(app, words2Search, columnName)

            % SUBSTITUIR CONSULTA À TABELA ARMAZENADA NO COMPONENTE DA GUI
            % PELA CONSULTA À TABELA APP.RAWSCHTABLE. AO INVÉS DO NÚMERO DA
            % COLUNA, PASSAR COMO ARGUMENTO O NOME DA COLUNA.

            filteredIndex = app.search_Table.UserData.PrimaryIndex;
            ComplIndex = contains(app.rawSCHTable{filteredIndex, sprintf('_%s', columnName)}, words2Search);
            
            app.search_Table.Data = app.rawSCHTable(filteredIndex(ComplIndex), 1:11);
            layout_checkTableVisibility(app)

      end
    end      
        %-----------------------------------------------------------------%       
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                % !! SE FOR COMPILAR COMO APP DESKTOP, TEREI QUE COPIAR TRECHO
                % DO APPANALISE PARA AQUI !!
                app.RootFolder = fileparts(mfilename('fullpath'));

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')

                % STARTUP
                app.Grid.ColumnWidth = {'1x', 0, 0, 0};
                startup_WindowPosition(app)
                startup_ConfigFiles(app)
                startup_ReadRawSCH(app)
                startup_jsBackDoor(app)
                

                % LISTBOX
                nMaxValues = class.Constants.NumberOfValues;
                app.search_Suggestions.ItemsData = 1:nMaxValues;
                if nMaxValues < 20
                    app.DocumentGrid.RowHeight{4} = 129;
                else
                    app.DocumentGrid.RowHeight{4} = 329;
                end

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isempty(app.CallingApp)
                app.CallingApp.auxiliarWin8 = [];
            end

            delete(app)
            
        end

        % Double-clicked callback: search_Table
        function search_TableDoubleClicked(app, event)
            
            % displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            % app.search_Table.Data{displayRow,1};
            % app.NomedofabricanteEditField.Value = string(app.search_Table.Data{displayRow,1});
            % app.ModeloEditField.Value = string(app.search_Table.Data{displayRow,2});
            % app.NomeComercialEditField.Value = string(app.search_Table.Data{displayRow,3});
            % app.TipodeprodutoEditField.Value = string(app.search_Table.Data{displayRow,4});
            % app.NmerodehomologaoEditField.Value = string(app.search_Table.Data{displayRow,5});
            % app.SituaodorequerimentoEditField.Value = string(app.search_Table.Data{displayRow,6});
            % app.NomedosolicitanteEditField.Value = string(app.search_Table.Data{displayRow,7});
            % app.CNPJdosolicitanteEditField.Value = string(app.search_Table.Data{displayRow,8});
            % app.CertificadodeconformidadeEditField.Value = string(app.search_Table.Data{displayRow,9});
            % app.ValidadedocertificadoEditField.Value = string(app.search_Table.Data{displayRow,10});        
            % app.SituaodocertificadoEditField.Value = string(app.search_Table.Data{displayRow,11});
            
        end

        % Callback function
        function ListBoxDoubleClicked(app, event)
           
            % app.filter_userEntry.Value = app.search_Suggestions.Items{event.InteractionInformation.Item};
            % filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))
            
        end

        % Callback function
        function ImageClicked(app, event)
            
            app.filter_userEntry.Value = app.previousSearch;
            filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))

        end

        % Callback function
        function filter_MethodSelectionChanged(app, event)
            
            app.lastSearch = '';
            filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))
            
        end

        % Callback function
        function FiltroButtonPushed(app, event)

           value2search = app.search_entryPoint.Value;
           idxColumn    = find(strcmp(app.rawSCHTable.Properties.VariableNames, app.filter_TypePanel_2.SelectedObject.Text), 1);

           filteredLogicalIndex = contains(string(app.rawSCHTable{:,idxColumn}), value2search, "IgnoreCase", true);
           app.search_Table.Data = app.rawSCHTable(filteredLogicalIndex,:);
           drawnow

        end

        % Value changing function: search_entryPoint
        function search_entryPointValueChanging(app, event)
            
            value2Search = fcn.PreProcessedData(event.Value);
            
            FilterType = filter_Columns_Type(app);
      
            if isempty(value2Search)
                set(app.search_Suggestions, Visible=0, Value={})

            else
                idxFiltered     = [];

                cacheStringList = FindCache_StringListOfSelectedColumn(app, FilterType);
                cacheTokenList  = [];

                nMaxValues      = class.Constants.NumberOfValues;
                
                algorithmsOrder = {'startsWith', 'Contains', 'Levenshtein'};
                redFontFlag     = false;

                for kk = algorithmsOrder
                    methodName = char(kk);

                    if strcmp(methodName, 'Levenshtein')
                        if isempty(idxFiltered)
                            redFontFlag = true;
                        end
                        cacheTokenList = FindCache_TokenListOfSelectedColumn(app, FilterType);
                    end

                    idxFiltered = fcn.getSimilarStrings(methodName, cacheStringList, cacheTokenList, value2Search, idxFiltered, nMaxValues);
                    if numel(idxFiltered) >= nMaxValues
                        break
                    end
                end

                set(app.search_Suggestions, 'Visible', 1, 'Value', {}, 'Items', cacheStringList(idxFiltered))                    
                layout_EntryPointColor(app, redFontFlag)
            end

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            hitObject = struct(event).HitObject;
            switch hitObject
                case app.search_entryPoint
                    switch app.search_entryPoint.Value
                        case 'O que você quer pesquisar?'
                            set(app.search_entryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if ~isempty(app.search_entryPoint.Value)
                                app.search_Suggestions.Visible = 1;
                            end
                    end

                case app.search_Suggestions
                    % Aparentemente não faz nada...

                otherwise
                    set(app.search_Suggestions, Visible=0, Value={})
                    if isempty(app.search_entryPoint.Value)
                        set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                    end                    
            end

        end

        % Image clicked function: Image, Image_4, Image_5, Image_6
        function layout_PanelVisibility(app, event)
            
            switch event.Source
                case app.Image                                              % Tela inicial
                    app.Grid.ColumnWidth(2:4) = {0,0,0};
                case app.Image_6                                            % Filtro complementar
                    app.Grid.ColumnWidth(2:4) = {300,0,0};
                case app.Image_4                                            % Enriquecimento da base
                    app.Grid.ColumnWidth(2:4) = {0,300,0};
                case app.Image_5                                            % Relatório
                    app.Grid.ColumnWidth(2:4) = {0,0,300};
            end

        end

        % Image clicked function: search_entryPointImage
        function search_entryPointImageClicked(app, event)
            
            value2Search  = app.search_entryPoint.Value;
            words2Search  = app.search_Suggestions.Items;

            FilterType = filter_Columns_Type(app);

            % TÍTULO DA BUSCA

            app.search_Metadata.Text = sprintf(['Exibindo resultados para "<b>%s</b>"\n' ...
                                                '<p style="color: #808080; font-size:10px;">Palavras-chave: %s</p>' ...
                                                '<p style="color: #808080; font-size:10px;">Pesquisa realizada na(s) coluna(s) %s</p>'], value2Search, strjoin("""" + string(words2Search) + """", ', '),strjoin("""" + string(FilterType) + """", ', '));
            
            % FILTRAGEM
            filter_main(app, words2Search, FilterType);
            layout_checkTableVisibility(app);

        end

        % Value changed function: filter_CertificationStatusCheckBox
        function filter_CertificationStatusCheckBoxValueChanged(app, event)
            
            if app.filter_CertificationStatusCheckBox.Value
                app.filter_CertificationStatus.Enable = "on";

                primaryIndex = app.search_Table.UserData.PrimaryIndex;
                columnName   = app.filter_CertificationStatusCheckBox.Text;

                app.filter_CertificationStatus.Items = [{''}; cellstr(unique(app.rawSCHTable{primaryIndex, columnName}))];

            else
                app.filter_CertificationStatus.Enable = "off";
                app.filter_CertificationStatus.Items  = {};

                secundaryFilterTrigger(app)
            end

        end

        % Value changed function: filter_ProductListCheckBox
        function filter_ProductListCheckBoxValueChanged(app, event)
            
            value = app.filter_ProductListCheckBox.Value;
                if value == 1
                   app.filter_ProductList.Enable = "on";
                   app.filter_ProductList.Items  = "";
                else
                   app.filter_ProductList.Items  = "";
                   app.filter_ProductList.Enable = "off";
                   app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,:);
                end
        end

        % Callback function: not associated with a component
        function filter_ProductListDropDownOpening(app, event)
                      
            columnName = string(app.filter_ProductListCheckBox.Text);
            app.filter_ProductList.Items =  string(table2cell(unique(app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,columnName))));

        end

        % Callback function: not associated with a component
        function filter_ProductListValueChanged(app, event)
            
            words2Search  = fcn.PreProcessedData(event.Value);
            ColumnName = app.filter_ProductListCheckBox.Text;
            filteredComplIndex(app, words2Search, ColumnName);

        end

        % Callback function: filter_Applicant, filter_CNPJ, 
        % ...and 4 other components
        function secundaryFilterTrigger(app, event)

            primaryIndex = app.search_Table.UserData.PrimaryIndex;
            checkboxList = findobj(app.filter_Panel2Grid, 'Type', 'uicheckbox', 'Value', 1);            

            if ~isempty(checkboxList)
                columnNames   = arrayfun(@(x) x.Text, checkboxList, 'UniformOutput', false);
                logicalMatrix = zeros(numel(primaryIndex), numel(columnNames), 'logical');
    
                for ii = 1:numel(columnNames)
                    columnName = columnNames{ii};

                    if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                        value2Search = fcn.PreProcessedData(event.Value);
                    else
                        editfieldObj = findobj(app.filter_Panel2Grid, 'Tag', columnName);
                        value2Search = fcn.PreProcessedData(editfieldObj.Value);                        
                    end
    
                    logicalMatrix(:,ii) = contains(app.rawSCHTable{primaryIndex, sprintf('_%s', columnName)}, value2Search);                
                end
                logicalArray = all(logicalMatrix, 2);
                    
                app.search_Table.Data = app.rawSCHTable(primaryIndex(logicalArray), 1:11);         

            else
                app.search_Table.Data = app.rawSCHTable(primaryIndex, 1:11);
            end
            drawnow
            
        end

        % Value changed function: filter_ApplicantCheckBox
        function filter_ApplicantCheckBoxValueChanged(app, event)
            
            value = app.filter_ApplicantCheckBox.Value;
                if value == 1
                   app.filter_Applicant.Enable = "on";
                   app.filter_Applicant.Value  = "";
                   app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,:);
                else
                   app.filter_Applicant.Value  = "";
                   app.filter_Applicant.Enable = "off";
                   app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,:);
                end

        end

        % Value changed function: filter_ManufacturerListCheckBox
        function filter_ManufacturerListCheckBoxValueChanged(app, event)
            value = app.filter_ManufacturerListCheckBox.Value;
                if value == 1
                   app.filter_ManufacturerList.Enable = "on";
                   app.filter_ManufacturerList.Value  = "";
                else
                   app.filter_ManufacturerList.Value  = "";
                   app.filter_ManufacturerList.Enable = "off";
                   app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,:);
                end
        end

        % Callback function: not associated with a component
        function filter_ManufacturerListValueChanging(app, event)
            
            words2Search  = fcn.PreProcessedData(event.Value);
            ColumnName = app.filter_ManufacturerListCheckBox.Text;
            filteredComplIndex(app, words2Search, ColumnName);

        end

        % Callback function: not associated with a component
        function filter_ApplicantValueChanging(app, event)

            words2Search  = fcn.PreProcessedData(event.Value);
            ColumnName = app.filter_ApplicantCheckBox.Text;
            filteredComplIndex(app, words2Search, ColumnName);

        end

        % Callback function: not associated with a component
        function filter_CNPJValueChanging(app, event)

            words2Search  = fcn.PreProcessedData(event.Value);
            ColumnName = app.filter_CNPJCheckBox.Text;
            filteredComplIndex(app, words2Search, ColumnName);

        end

        % Callback function: not associated with a component
        function filter_CertificationPart1ValueChanging(app, event)
            
            words2Search  = fcn.PreProcessedData(event.Value);
            columnName = app.filter_CertificationCheckBox.Text;
            filteredComplIndex(app, words2Search, columnName);

        end

        % Value changed function: filter_CertificationCheckBox
        function filter_CertificationCheckBoxValueChanged(app, event)
            
                if app.filter_CertificationCheckBox.Value
                   app.filter_CertificationPart1.Enable = "on";
                   app.filter_CertificationPart1.Value  = "";

                else
                   app.filter_CertificationPart1.Value  = "";
                   app.filter_CertificationPart1.Enable = "off";

                   secundaryFilterTrigger(app)
                end

        end

        % Value changed function: filter_CNPJCheckBox
        function filter_CNPJCheckBoxValueChanged(app, event)
            value = app.filter_CNPJCheckBox.Value;
                if value == 1
                   app.filter_CNPJ.Enable = "on";
                   app.filter_CNPJ.Value  = "";
                else
                   app.filter_CNPJ.Value  = "";
                   app.filter_CNPJ.Enable = "off";
                   app.search_Table.Data =   app.rawSCHTable(app.search_Table.UserData.PrimaryIndex,:);
                end
        end

        % Value changed function: filter_CheckBox_Manufacturer
        function filter_CheckBox_ManufacturerValueChanged(app, event)

            search_entryPointImageClicked(app)
            
        end

        % Value changed function: filter_CheckBox_ProductName
        function filter_CheckBox_ProductNameValueChanged(app, event)
            search_entryPointImageClicked(app);
            
        end

        % Value changed function: filter_CheckBox_ProductModel
        function filter_CheckBox_ProductModelValueChanged(app, event)
           search_entryPointImageClicked(app)
            
        end

        % Value changed function: filter_CheckBox_Notes
        function filter_CheckBox_NotesValueChanged(app, event)
            search_entryPointImageClicked(app)
            
        end

        % Value changed function: filter_CheckBox_Applicant
        function filter_CheckBox_ApplicantValueChanged(app, event)
            search_entryPointImageClicked(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'blueIcon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.Document = uigridlayout(app.Grid);
            app.DocumentGrid = uigridlayout(app.Document);
            app.search_entryPointPanel = uipanel(app.DocumentGrid);
            app.search_entryPointGrid = uigridlayout(app.search_entryPointPanel);
            app.search_entryPointImage = uiimage(app.search_entryPointGrid);
            app.search_entryPointImage.ImageClickedFcn = createCallbackFcn(app, @search_entryPointImageClicked, true);
            app.search_entryPointImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_zoom.png');
            app.search_entryPoint = uieditfield(app.search_entryPointGrid, 'text');
            app.search_entryPoint.CharacterLimits = [0 128];
            app.search_entryPoint.ValueChangingFcn = createCallbackFcn(app, @search_entryPointValueChanging, true);
            app.search_entryPoint.Tag = 'PROMPT';
            app.search_entryPoint.Value = 'O que você quer pesquisar?';
            app.search_Metadata = uilabel(app.DocumentGrid);
            app.search_Metadata.Visible = 'off';
            app.search_Metadata.Interpreter = 'html';
            app.search_Metadata.Text = {'Exibindo resultados para "<b>apple iphone</b>"'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.search_Table = uitable(app.DocumentGrid);
            app.search_Table.ColumnName = {'auto'};
            app.search_Table.RowName = {};
            app.search_Table.ColumnSortable = true;
            app.search_Table.SelectionType = 'row';
            app.search_Table.DoubleClickedFcn = createCallbackFcn(app, @search_TableDoubleClicked, true);
            app.search_Table.Multiselect = 'off';
            app.search_Table.Visible = 'off';
            app.search_Suggestions = uilistbox(app.DocumentGrid);
            app.search_Suggestions.Items = {'One', 'Two', 'Three', 'Four', 'Five', 'Six', 'Seven', 'Eight', 'Nine', 'Ten'};
            app.search_Suggestions.Tag = 'CAIXA DE BUSCA';
            app.search_Suggestions.Visible = 'off';
            app.search_Suggestions.Value = 'One';
            app.GridLayout = uigridlayout(app.DocumentGrid);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @layout_PanelVisibility, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'WHITEnew_image.png');
            app.Image_2 = uiimage(app.GridLayout);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_Dots.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_LineH.png');
            app.Image_4 = uiimage(app.GridLayout);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @layout_PanelVisibility, true);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_edit.png');
            app.Image_5 = uiimage(app.GridLayout);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @layout_PanelVisibility, true);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_report.png');
            app.Image_6 = uiimage(app.GridLayout);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @layout_PanelVisibility, true);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'Icons', 'DT_Filter.png');
            app.tool_nRowsGrid = uigridlayout(app.DocumentGrid);
            app.tool_nRowsIcon = uiimage(app.tool_nRowsGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.Visible = 'off';
            app.tool_nRowsIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Filter.png');
            app.tool_nRows = uilabel(app.tool_nRowsGrid);
            app.tool_nRows.Visible = 'off';
            app.tool_nRows.Text = {'0 de 979522 registros'; '0.0 %'};
            app.jsBackDoor = uihtml(app.DocumentGrid);
            app.Tab3Grid = uigridlayout(app.Grid);
            app.report_TreeLabel = uilabel(app.Tab3Grid);
            app.report_TreeLabel.Text = 'LISTA DE PRODUTOS SOB ANÁLISE';
            app.report_Tree = uitree(app.Tab3Grid);
            app.Node = uitreenode(app.report_Tree);
            app.Node.Text = '#1: 04546-08-09892';
            app.APPLEIPHONE22Node = uitreenode(app.report_Tree);
            app.APPLEIPHONE22Node.Text = '#2: APPLE IPHONE 22';
            app.Node_3 = uitreenode(app.report_Tree);
            app.Node_3.Text = '#3: 04546-08-09893';
            app.report_SelectedProductLabel = uilabel(app.Tab3Grid);
            app.report_SelectedProductLabel.Text = 'CARACTERÍSTICAS DO PRODUTO SELECIONADO';
            app.report_SelectedProductPanel = uipanel(app.Tab3Grid);
            app.report_SelectedProductGrid = uigridlayout(app.report_SelectedProductPanel);
            app.report_QuantityLabel = uilabel(app.report_SelectedProductGrid);
            app.report_QuantityLabel.Text = 'Quantidade:';
            app.report_Quantity = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_MonetaryValueLabel = uilabel(app.report_SelectedProductGrid);
            app.report_MonetaryValueLabel.Text = 'Valor unitário (R$):';
            app.report_MonetaryValue = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_ComplementaryNotesLabel = uilabel(app.report_SelectedProductGrid);
            app.report_ComplementaryNotesLabel.Text = 'Informações complementares:';
            app.report_ComplementaryNotes = uitextarea(app.report_SelectedProductGrid);
            app.TipoDropDownLabel = uilabel(app.report_SelectedProductGrid);
            app.TipoDropDownLabel.Text = 'Tipo:';
            app.TipoDropDown = uidropdown(app.report_SelectedProductGrid);
            app.TipoDropDown.Items = {''};
            app.TipoDropDown.Value = '';
            app.Tab2Grid = uigridlayout(app.Grid);
            app.notes_Label1 = uilabel(app.Tab2Grid);
            app.notes_Label1.Text = 'PRODUTO';
            app.notes_Panel1 = uipanel(app.Tab2Grid);
            app.notes_Panel1Grid = uigridlayout(app.notes_Panel1);
            app.notes_ProductInfo = uihtml(app.notes_Panel1Grid);
            app.notes_Label2 = uilabel(app.Tab2Grid);
            app.notes_Label2.Text = 'ANOTAÇÃO';
            app.notes_Panel2 = uipanel(app.Tab2Grid);
            app.notes_Panel2Grid = uigridlayout(app.notes_Panel2);
            app.notes_TypeLabel = uilabel(app.notes_Panel2Grid);
            app.notes_TypeLabel.Text = 'Tipo:';
            app.notes_Type = uidropdown(app.notes_Panel2Grid);
            app.notes_Type.Items = {'Fornecedor', 'Fabricante', 'Modelo comercial', 'Outras informações', 'Termos relacionados (Google)'};
            app.notes_Type.Value = 'Fornecedor';
            app.notes_ValueLabel = uilabel(app.notes_Panel2Grid);
            app.notes_ValueLabel.Text = 'Valor:';
            app.notes_Value = uitextarea(app.notes_Panel2Grid);
            app.notes_addImage = uiimage(app.Tab2Grid);
            app.notes_addImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Add.png');
            app.notes_Tree = uitree(app.Tab2Grid);
            app.Tab1Grid = uigridlayout(app.Grid);
            app.filter_Label1 = uilabel(app.Tab1Grid);
            app.filter_Label1.Text = 'FILTRO TEXTUAL';
            app.filter_Panel1 = uipanel(app.Tab1Grid);
            app.filter_Panel1Grid = uigridlayout(app.filter_Panel1);
            app.filter_CheckBox_Applicant = uicheckbox(app.filter_Panel1Grid);
            app.filter_CheckBox_Applicant.ValueChangedFcn = createCallbackFcn(app, @filter_CheckBox_ApplicantValueChanged, true);
            app.filter_CheckBox_Applicant.Text = 'Nome do Solicitante';
            app.filter_CheckBox_Manufacturer = uicheckbox(app.filter_Panel1Grid);
            app.filter_CheckBox_Manufacturer.ValueChangedFcn = createCallbackFcn(app, @filter_CheckBox_ManufacturerValueChanged, true);
            app.filter_CheckBox_Manufacturer.Text = 'Nome do Fabricante';
            app.filter_CheckBox_ProductName = uicheckbox(app.filter_Panel1Grid);
            app.filter_CheckBox_ProductName.ValueChangedFcn = createCallbackFcn(app, @filter_CheckBox_ProductNameValueChanged, true);
            app.filter_CheckBox_ProductName.Text = 'Nome Comercial';
            app.filter_CheckBox_ProductName.Value = true;
            app.filter_CheckBox_ProductModel = uicheckbox(app.filter_Panel1Grid);
            app.filter_CheckBox_ProductModel.ValueChangedFcn = createCallbackFcn(app, @filter_CheckBox_ProductModelValueChanged, true);
            app.filter_CheckBox_ProductModel.Text = 'Modelo';
            app.filter_CheckBox_Notes = uicheckbox(app.filter_Panel1Grid);
            app.filter_CheckBox_Notes.ValueChangedFcn = createCallbackFcn(app, @filter_CheckBox_NotesValueChanged, true);
            app.filter_CheckBox_Notes.Text = 'Anotações';
            app.filter_Label2 = uilabel(app.Tab1Grid);
            app.filter_Label2.Text = 'FILTROS COMPLEMENTARES';
            app.filter_Panel2 = uipanel(app.Tab1Grid);
            app.filter_Panel2Grid = uigridlayout(app.filter_Panel2);
            app.filter_CertificationCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_CertificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_CertificationCheckBoxValueChanged, true);
            app.filter_CertificationCheckBox.Text = 'Número de Homologação';
            app.filter_CertificationStatusCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_CertificationStatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_CertificationStatusCheckBoxValueChanged, true);
            app.filter_CertificationStatusCheckBox.Text = 'Situação do Certificado';
            app.filter_CertificationStatus = uidropdown(app.filter_Panel2Grid);
            app.filter_CertificationStatus.Items = {''};
            app.filter_CertificationStatus.ValueChangedFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_CertificationStatus.Tag = 'Situação do Certificado';
            app.filter_CertificationStatus.Enable = 'off';
            app.filter_CertificationStatus.Value = '';
            app.filter_ProductListCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_ProductListCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_ProductListCheckBoxValueChanged, true);
            app.filter_ProductListCheckBox.Text = 'Tipo do Produto';
            app.filter_ProductList = uidropdown(app.filter_Panel2Grid);
            app.filter_ProductList.Items = {''};
            app.filter_ProductList.ValueChangedFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_ProductList.Tag = 'Tipo do Produto';
            app.filter_ProductList.Enable = 'off';
            app.filter_ProductList.Value = '';
            app.filter_ManufacturerListCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_ManufacturerListCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_ManufacturerListCheckBoxValueChanged, true);
            app.filter_ManufacturerListCheckBox.Text = 'Nome do Fabricante';
            app.filter_ManufacturerList = uieditfield(app.filter_Panel2Grid, 'text');
            app.filter_ManufacturerList.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_ManufacturerList.Tag = 'Nome do Fabricante';
            app.filter_ManufacturerList.Enable = 'off';
            app.filter_ApplicantCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_ApplicantCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_ApplicantCheckBoxValueChanged, true);
            app.filter_ApplicantCheckBox.Text = 'Nome do Solicitante';
            app.filter_Applicant = uieditfield(app.filter_Panel2Grid, 'text');
            app.filter_Applicant.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_Applicant.Tag = 'Nome do Solicitante';
            app.filter_Applicant.Enable = 'off';
            app.filter_CNPJCheckBox = uicheckbox(app.filter_Panel2Grid);
            app.filter_CNPJCheckBox.ValueChangedFcn = createCallbackFcn(app, @filter_CNPJCheckBoxValueChanged, true);
            app.filter_CNPJCheckBox.Text = 'CNPJ do Solicitante';
            app.filter_CNPJ = uieditfield(app.filter_Panel2Grid, 'text');
            app.filter_CNPJ.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_CNPJ.Tag = 'CNPJ do Solicitante';
            app.filter_CNPJ.Enable = 'off';
            app.filter_CertificationPart1 = uieditfield(app.filter_Panel2Grid, 'text');
            app.filter_CertificationPart1.ValueChangingFcn = createCallbackFcn(app, @secundaryFilterTrigger, true);
            app.filter_CertificationPart1.Tag = 'Número de Homologação';
            app.filter_CertificationPart1.Enable = 'off';
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_referenceButton = uimenu(app.points_ContextMenu);
            app.points_referenceButton.Text = 'Referência';
            app.points_editRowButton = uimenu(app.points_ContextMenu);
            app.points_editRowButton.Separator = 'on';
            app.points_editRowButton.Text = 'Editar';
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end